classdef with_memory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        DEditField        matlab.ui.control.NumericEditField
        DEditFieldLabel   matlab.ui.control.Label
        EEditField        matlab.ui.control.NumericEditField
        EEditFieldLabel   matlab.ui.control.Label
        m2EditField       matlab.ui.control.NumericEditField
        m2Label           matlab.ui.control.Label
        m1EditField       matlab.ui.control.NumericEditField
        m1EditFieldLabel  matlab.ui.control.Label
        AEditField        matlab.ui.control.NumericEditField
        bEditField        matlab.ui.control.NumericEditField
        eEditField        matlab.ui.control.NumericEditField
        aEditField        matlab.ui.control.NumericEditField
        cEditField        matlab.ui.control.NumericEditField
        dEditField        matlab.ui.control.NumericEditField
        BEditField        matlab.ui.control.NumericEditField
        m3EditField       matlab.ui.control.NumericEditField
        ASlider           matlab.ui.control.Slider
        ASliderLabel      matlab.ui.control.Label
        m3Slider          matlab.ui.control.Slider
        m3SliderLabel     matlab.ui.control.Label
        eSlider           matlab.ui.control.Slider
        eSliderLabel      matlab.ui.control.Label
        dSlider           matlab.ui.control.Slider
        dSliderLabel      matlab.ui.control.Label
        cSlider           matlab.ui.control.Slider
        cSliderLabel      matlab.ui.control.Label
        bSlider           matlab.ui.control.Slider
        bSliderLabel      matlab.ui.control.Label
        BSlider           matlab.ui.control.Slider
        BSliderLabel      matlab.ui.control.Label
        aSlider           matlab.ui.control.Slider
        aSliderLabel      matlab.ui.control.Label
        WithMemoryLabel   matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        UIAxes_IO         matlab.ui.control.UIAxes
        UIAxes_DF_Im      matlab.ui.control.UIAxes
        UIAxes_DF         matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: BSlider
        function BSliderValueChanging(app, event)
            B = event.Value;
            m3 = app.m3Slider.Value;
            a = app.aSlider.Value;
            c = app.cSlider.Value;
            b = app.bSlider.Value;
            A = app.ASlider.Value;
            d = app.dSlider.Value;
            e = app.eSlider.Value;
            
            % Constraints
            m1=B/(b-a);
            E=m1*(c-d);
            D=m1*(e-a);
            app.BEditField.Value = B;
            m2=(D-E)/(e-c);
            app.m1EditField.Value = m1;
            app.m2EditField.Value = m2;
            app.EEditField.Value = E;
            app.DEditField.Value = D;
            
            %Plot nonlinearity
            x1 = [-e -c -d a b A];
            y1 = [-D -E 0 0 B B+m3*(A-b)];
            x2 = [-A -b -a d c e];
            y2 = [-B-m3*(A-b) -B 0 0 E D];
            plot(app.UIAxes,x1,y1,x2,y2);

            % plot input sin
            A = app.ASlider.Value;
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a/A);
            theta_2 = asin(b/A);
            theta_3 = asin(c/A);
            theta_4 = asin(d/A);
            theta_5 = asin(e/A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;
            
            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            y_output = [0 B y_rad1 B D E 0 0 -B y_rad2 -B -D -E 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            legend(app.UIAxes_IO,{'input','output'},'Location','southwest');
            
            % add key points
            keyp_x = [theta_1 theta_2 pi/2 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 3*pi/2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            keyp_y = [0 B H+M B D E 0 0 -B -H-M -B -D -E 0];
%           formatSpec = '$\textcircled{%d}$';
            formatSpec = '\n{%d}';
            
            for i = 1:length(keyp_x)
                str_index = sprintf(formatSpec,i);
                text(app.UIAxes_IO, keyp_x(i),keyp_y(i),str_index,'Interpreter','latex')
            end
            
            % describing function
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            theta_3 = asin(c./A);
            theta_4 = asin(d./A);
            theta_5 = asin(e./A);
            g_A = (1/pi) * (- m1*(theta_1+theta_4) +...
                2*(m1-m3)*theta_2 + ...
                (m1-m2)*(theta_3-theta_5) + pi*m3 +...
                m1/2*(sin(2*theta_1)+sin(2*theta_4))-...
                (m1-m3)*sin(2*theta_2) - ...
                (m1-m2)/2*(sin(2*theta_3)-sin(2*theta_5))-...
                2*m1*a./A.*cos(theta_1) +...
                4*(m1-m3)*b./A.*cos(theta_2) +...
                2*(m1-m2)*c./A.*cos(theta_3) -...
                2*m1*d./A.*cos(theta_4)-...
                2*(m1-m2)*e./A.*cos(theta_5));
            plot(app.UIAxes_DF,A,g_A);
            b_A = (1/pi) * (m1/2*(cos(2*theta_1)-cos(2*theta_4)) + ...
                (m1-m2)/2*(cos(2*theta_3)-cos(2*theta_5)) + ...
                2*m1*a./A.*sin(theta_1) + ...
                2*(m1-m2)*c./A.*sin(theta_3) - ...
                2*m1*d./A.*sin(theta_4) - ...
                2*(m1-m2)*e./A.*sin(theta_5));
           plot(app.UIAxes_DF_Im,A,b_A);
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            a = event.Value;
            m3 = app.m3Slider.Value;
            B = app.BSlider.Value;
            c = app.cSlider.Value;
            b = app.bSlider.Value;
            A = app.ASlider.Value;
            d = app.dSlider.Value;
            e = app.eSlider.Value;
            
            % Constraints
            a = min(max(c,a),e);
            a= min(e+d-c, a);
            app.aEditField.Value = a;
            m1=B/(b-a);
            E=m1*(c-d);
            D=m1*(e-a);
            m2=(D-E)/(e-c);
            app.m1EditField.Value = m1;
            app.m2EditField.Value = m2;
            app.EEditField.Value = E;
            app.DEditField.Value = D;
            
            
            %Plot nonlinearity
            x1 = [-e -c -d a b A];
            y1 = [-D -E 0 0 B B+m3*(A-b)];
            x2 = [-A -b -a d c e];
            y2 = [-B-m3*(A-b) -B 0 0 E D];
            plot(app.UIAxes,x1,y1,x2,y2);
%             axis(app.UIAxes,[-4*b 4*b -2*B 2*B]);

            % plot input sin
            A = app.ASlider.Value;
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a/A);
            theta_2 = asin(b/A);
            theta_3 = asin(c/A);
            theta_4 = asin(d/A);
            theta_5 = asin(e/A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;
            
            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            y_output = [0 B y_rad1 B D E 0 0 -B y_rad2 -B -D -E 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            legend(app.UIAxes_IO,{'input','output'},'Location','southwest');
            
            % add key points
            keyp_x = [theta_1 theta_2 pi/2 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 3*pi/2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            keyp_y = [0 B H+M B D E 0 0 -B -H-M -B -D -E 0];
           
            %           formatSpec = '$\textcircled{%d}$';
            formatSpec = '\n{%d}';
            
            for i = 1:length(keyp_x)
                str_index = sprintf(formatSpec,i);
                text(app.UIAxes_IO, keyp_x(i),keyp_y(i),str_index,'Interpreter','latex')
            end
            
            % describing function
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            theta_3 = asin(c./A);
            theta_4 = asin(d./A);
            theta_5 = asin(e./A);
            g_A = (1/pi) * (- m1*(theta_1+theta_4) +...
                2*(m1-m3)*theta_2 + ...
                (m1-m2)*(theta_3-theta_5) + pi*m3 +...
                m1/2*(sin(2*theta_1)+sin(2*theta_4))-...
                (m1-m3)*sin(2*theta_2) - ...
                (m1-m2)/2*(sin(2*theta_3)-sin(2*theta_5))-...
                2*m1*a./A.*cos(theta_1) +...
                4*(m1-m3)*b./A.*cos(theta_2) +...
                2*(m1-m2)*c./A.*cos(theta_3) -...
                2*m1*d./A.*cos(theta_4)-...
                2*(m1-m2)*e./A.*cos(theta_5));
            plot(app.UIAxes_DF,A,g_A);
            b_A = (1/pi) * (m1/2*(cos(2*theta_1)-cos(2*theta_4)) + ...
                (m1-m2)/2*(cos(2*theta_3)-cos(2*theta_5)) + ...
                2*m1*a./A.*sin(theta_1) + ...
                2*(m1-m2)*c./A.*sin(theta_3) - ...
                2*m1*d./A.*sin(theta_4) - ...
                2*(m1-m2)*e./A.*sin(theta_5));
           plot(app.UIAxes_DF_Im,A,b_A);
        end

        % Value changing function: cSlider
        function cSliderValueChanging(app, event)
            c = event.Value;
            m3 = app.m3Slider.Value;
            B = app.BSlider.Value;
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            A = app.ASlider.Value;
            d = app.dSlider.Value;
            e = app.eSlider.Value;
            
            % Constraints
            c = min(max(d,c),a);
            c= min(e-a+d, c);
            app.cEditField.Value = c;
            m1=B/(b-a);
            E=m1*(c-d);
            D=m1*(e-a);
            m2=(D-E)/(e-c);
            app.m1EditField.Value = m1;
            app.m2EditField.Value = m2;
            app.EEditField.Value = E;
            app.DEditField.Value = D;


            %Plot nonlinearity
            x1 = [-e -c -d a b A];
            y1 = [-D -E 0 0 B B+m3*(A-b)];
            x2 = [-A -b -a d c e];
            y2 = [-B-m3*(A-b) -B 0 0 E D];
            plot(app.UIAxes,x1,y1,x2,y2);
%             axis(app.UIAxes,[-4*b 4*b -2*B 2*B]);

            % plot input sin
            A = app.ASlider.Value;
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a/A);
            theta_2 = asin(b/A);
            theta_3 = asin(c/A);
            theta_4 = asin(d/A);
            theta_5 = asin(e/A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;
            
            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            y_output = [0 B y_rad1 B D E 0 0 -B y_rad2 -B -D -E 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            legend(app.UIAxes_IO,{'input','output'},'Location','southwest');
            
            % add key points
            keyp_x = [theta_1 theta_2 pi/2 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 3*pi/2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            keyp_y = [0 B H+M B D E 0 0 -B -H-M -B -D -E 0];
            %           formatSpec = '$\textcircled{%d}$';
            formatSpec = '\n{%d}';
            
            for i = 1:length(keyp_x)
                str_index = sprintf(formatSpec,i);
                text(app.UIAxes_IO, keyp_x(i),keyp_y(i),str_index,'Interpreter','latex')
            end
            
            % describing function
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            theta_3 = asin(c./A);
            theta_4 = asin(d./A);
            theta_5 = asin(e./A);
            g_A = (1/pi) * (- m1*(theta_1+theta_4) +...
                2*(m1-m3)*theta_2 + ...
                (m1-m2)*(theta_3-theta_5) + pi*m3 +...
                m1/2*(sin(2*theta_1)+sin(2*theta_4))-...
                (m1-m3)*sin(2*theta_2) - ...
                (m1-m2)/2*(sin(2*theta_3)-sin(2*theta_5))-...
                2*m1*a./A.*cos(theta_1) +...
                4*(m1-m3)*b./A.*cos(theta_2) +...
                2*(m1-m2)*c./A.*cos(theta_3) -...
                2*m1*d./A.*cos(theta_4)-...
                2*(m1-m2)*e./A.*cos(theta_5));
            plot(app.UIAxes_DF,A,g_A);
            b_A = (1/pi) * (m1/2*(cos(2*theta_1)-cos(2*theta_4)) + ...
                (m1-m2)/2*(cos(2*theta_3)-cos(2*theta_5)) + ...
                2*m1*a./A.*sin(theta_1) + ...
                2*(m1-m2)*c./A.*sin(theta_3) - ...
                2*m1*d./A.*sin(theta_4) - ...
                2*(m1-m2)*e./A.*sin(theta_5));
           plot(app.UIAxes_DF_Im,A,b_A);
            
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            b = event.Value;
            m3 = app.m3Slider.Value;
            B = app.BSlider.Value;
            c = app.cSlider.Value;
            a = app.aSlider.Value;
            A = app.ASlider.Value;
            d = app.dSlider.Value;
            e = app.eSlider.Value;
            
            % constraints on a,b,c
            b = min(max(e,b),A);
            app.bEditField.Value = b;
            m1=B/(b-a);
            E=m1*(c-d);
            D=m1*(e-a);
            m2=(D-E)/(e-c);
            app.m1EditField.Value = m1;
            app.m2EditField.Value = m2;
            app.EEditField.Value = E;
            app.DEditField.Value = D;
            
            
            %Plot nonlinearity
            x1 = [-e -c -d a b A];
            y1 = [-D -E 0 0 B B+m3*(A-b)];
            x2 = [-A -b -a d c e];
            y2 = [-B-m3*(A-b) -B 0 0 E D];
            plot(app.UIAxes,x1,y1,x2,y2);
%             axis(app.UIAxes,[-4*b 4*b -2*B 2*B]);

            % plot input sin
            A = app.ASlider.Value;
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a/A);
            theta_2 = asin(b/A);
            theta_3 = asin(c/A);
            theta_4 = asin(d/A);
            theta_5 = asin(e/A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;
            
            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            y_output = [0 B y_rad1 B D E 0 0 -B y_rad2 -B -D -E 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            legend(app.UIAxes_IO,{'input','output'},'Location','southwest');
            
            % add key points
            keyp_x = [theta_1 theta_2 pi/2 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 3*pi/2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            keyp_y = [0 B H+M B D E 0 0 -B -H-M -B -D -E 0];
            %           formatSpec = '$\textcircled{%d}$';
            formatSpec = '\n{%d}';
            
            for i = 1:length(keyp_x)
                str_index = sprintf(formatSpec,i);
                text(app.UIAxes_IO, keyp_x(i),keyp_y(i),str_index,'Interpreter','latex')
            end
            
            % describing function
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            theta_3 = asin(c./A);
            theta_4 = asin(d./A);
            theta_5 = asin(e./A);
            g_A = (1/pi) * (- m1*(theta_1+theta_4) +...
                2*(m1-m3)*theta_2 + ...
                (m1-m2)*(theta_3-theta_5) + pi*m3 +...
                m1/2*(sin(2*theta_1)+sin(2*theta_4))-...
                (m1-m3)*sin(2*theta_2) - ...
                (m1-m2)/2*(sin(2*theta_3)-sin(2*theta_5))-...
                2*m1*a./A.*cos(theta_1) +...
                4*(m1-m3)*b./A.*cos(theta_2) +...
                2*(m1-m2)*c./A.*cos(theta_3) -...
                2*m1*d./A.*cos(theta_4)-...
                2*(m1-m2)*e./A.*cos(theta_5));
            plot(app.UIAxes_DF,A,g_A);
            b_A = (1/pi) * (m1/2*(cos(2*theta_1)-cos(2*theta_4)) + ...
                (m1-m2)/2*(cos(2*theta_3)-cos(2*theta_5)) + ...
                2*m1*a./A.*sin(theta_1) + ...
                2*(m1-m2)*c./A.*sin(theta_3) - ...
                2*m1*d./A.*sin(theta_4) - ...
                2*(m1-m2)*e./A.*sin(theta_5));
           plot(app.UIAxes_DF_Im,A,b_A);
        end

        % Value changed function: dSlider
        function dSliderValueChanged(app, event)
value = app.dSlider.Value;

        end

        % Value changing function: dSlider
        function dSliderValueChanging(app, event)
            d = event.Value;
            m3 = app.m3Slider.Value;
            B = app.BSlider.Value;
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            A = app.ASlider.Value;
            c = app.cSlider.Value;
            e = app.eSlider.Value;
            
            % constraints on a,b,c
            d = min(c,d);
            d=max(c+a-e,d);
            app.dEditField.Value = d;
            m1=B/(b-a);
            E=m1*(c-d);
            D=m1*(e-a);
            m2=(D-E)/(e-c);
            app.m1EditField.Value = m1;
            app.m2EditField.Value = m2;
            app.EEditField.Value = E;
            app.DEditField.Value = D;
            
            %Plot nonlinearity
            x1 = [-e -c -d a b A];
            y1 = [-D -E 0 0 B B+m3*(A-b)];
            x2 = [-A -b -a d c e];
            y2 = [-B-m3*(A-b) -B 0 0 E D];
            plot(app.UIAxes,x1,y1,x2,y2);
            axis(app.UIAxes,[-4*b 4*b -2*B 2*B]);
            
            % plot input sin
            A = app.ASlider.Value;
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a/A);
            theta_2 = asin(b/A);
            theta_3 = asin(c/A);
            theta_4 = asin(d/A);
            theta_5 = asin(e/A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;
            
            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            y_output = [0 B y_rad1 B D E 0 0 -B y_rad2 -B -D -E 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            legend(app.UIAxes_IO,{'input','output'},'Location','southwest');
            
            % add key points
            keyp_x = [theta_1 theta_2 pi/2 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 3*pi/2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            keyp_y = [0 B H+M B D E 0 0 -B -H-M -B -D -E 0];
            %           formatSpec = '$\textcircled{%d}$';
            formatSpec = '\n{%d}';
            
            for i = 1:length(keyp_x)
                str_index = sprintf(formatSpec,i);
                text(app.UIAxes_IO, keyp_x(i),keyp_y(i),str_index,'Interpreter','latex')
            end
            
            % describing function
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            theta_3 = asin(c./A);
            theta_4 = asin(d./A);
            theta_5 = asin(e./A);
            g_A = (1/pi) * (- m1*(theta_1+theta_4) +...
                2*(m1-m3)*theta_2 + ...
                (m1-m2)*(theta_3-theta_5) + pi*m3 +...
                m1/2*(sin(2*theta_1)+sin(2*theta_4))-...
                (m1-m3)*sin(2*theta_2) - ...
                (m1-m2)/2*(sin(2*theta_3)-sin(2*theta_5))-...
                2*m1*a./A.*cos(theta_1) +...
                4*(m1-m3)*b./A.*cos(theta_2) +...
                2*(m1-m2)*c./A.*cos(theta_3) -...
                2*m1*d./A.*cos(theta_4)-...
                2*(m1-m2)*e./A.*cos(theta_5));
            plot(app.UIAxes_DF,A,g_A);
            b_A = (1/pi) * (m1/2*(cos(2*theta_1)-cos(2*theta_4)) + ...
                (m1-m2)/2*(cos(2*theta_3)-cos(2*theta_5)) + ...
                2*m1*a./A.*sin(theta_1) + ...
                2*(m1-m2)*c./A.*sin(theta_3) - ...
                2*m1*d./A.*sin(theta_4) - ...
                2*(m1-m2)*e./A.*sin(theta_5));
           plot(app.UIAxes_DF_Im,A,b_A);
            
        end

        % Value changing function: eSlider
        function eSliderValueChanging(app, event)
            
            e = event.Value;
            m3 = app.m3Slider.Value;
            B = app.BSlider.Value;
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            A = app.ASlider.Value;
            c = app.cSlider.Value;
            d = app.dSlider.Value;
            
            % constraints on a,b,c
            e = max(min(e,b),a);
            e= max(c-d+a, e);
            app.eEditField.Value = e;
            d=max(c+a-e,d);
            m1=B/(b-a);
            E=m1*(c-d);
            D=m1*(e-a);
            m2=(D-E)/(e-c);
            app.m1EditField.Value = m1;
            app.m2EditField.Value = m2;
            app.EEditField.Value = E;
            app.DEditField.Value = D;
            
            %Plot nonlinearity
            x1 = [-e -c -d a b A];
            y1 = [-D -E 0 0 B B+m3*(A-b)];
            x2 = [-A -b -a d c e];
            y2 = [-B-m3*(A-b) -B 0 0 E D];
            plot(app.UIAxes,x1,y1,x2,y2);
%           axis(app.UIAxes,[-4*b 4*b -2*B 2*B]);
            
            % plot input sin
            A = app.ASlider.Value;
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a/A);
            theta_2 = asin(b/A);
            theta_3 = asin(c/A);
            theta_4 = asin(d/A);
            theta_5 = asin(e/A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;
            
            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            y_output = [0 B y_rad1 B D E 0 0 -B y_rad2 -B -D -E 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            legend(app.UIAxes_IO,{'input','output'},'Location','southwest');
            
            % add key points
            keyp_x = [theta_1 theta_2 pi/2 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 3*pi/2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            keyp_y = [0 B H+M B D E 0 0 -B -H-M -B -D -E 0];
           %           formatSpec = '$\textcircled{%d}$';
            formatSpec = '\n{%d}';
            
            for i = 1:length(keyp_x)
                str_index = sprintf(formatSpec,i);
                text(app.UIAxes_IO, keyp_x(i),keyp_y(i),str_index,'Interpreter','latex')
            end
            
            % describing function
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            theta_3 = asin(c./A);
            theta_4 = asin(d./A);
            theta_5 = asin(e./A);
            g_A = (1/pi) * (- m1*(theta_1+theta_4) +...
                2*(m1-m3)*theta_2 + ...
                (m1-m2)*(theta_3-theta_5) + pi*m3 +...
                m1/2*(sin(2*theta_1)+sin(2*theta_4))-...
                (m1-m3)*sin(2*theta_2) - ...
                (m1-m2)/2*(sin(2*theta_3)-sin(2*theta_5))-...
                2*m1*a./A.*cos(theta_1) +...
                4*(m1-m3)*b./A.*cos(theta_2) +...
                2*(m1-m2)*c./A.*cos(theta_3) -...
                2*m1*d./A.*cos(theta_4)-...
                2*(m1-m2)*e./A.*cos(theta_5));
            plot(app.UIAxes_DF,A,g_A);
            b_A = (1/pi) * (m1/2*(cos(2*theta_1)-cos(2*theta_4)) + ...
                (m1-m2)/2*(cos(2*theta_3)-cos(2*theta_5)) + ...
                2*m1*a./A.*sin(theta_1) + ...
                2*(m1-m2)*c./A.*sin(theta_3) - ...
                2*m1*d./A.*sin(theta_4) - ...
                2*(m1-m2)*e./A.*sin(theta_5));
           plot(app.UIAxes_DF_Im,A,b_A);
        end

        % Value changing function: ASlider
        function ASliderValueChanging(app, event)

            A = event.Value;
            m3 = app.m3Slider.Value;
            B = app.BSlider.Value;
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            e = app.eSlider.Value;
            c = app.cSlider.Value;
            d = app.dSlider.Value;
            
            % constraints on a,b,c
            A =max(b,A);
            app.AEditField.Value = A;
            m1=B/(b-a);
            E=m1*(c-d);
            D=m1*(e-a);
            m2=(D-E)/(e-c);
            app.m1EditField.Value = m1;
            app.m2EditField.Value = m2;
            app.EEditField.Value = E;
            app.DEditField.Value = D;
            
            %Plot nonlinearity
            x1 = [-e -c -d a b A];
            y1 = [-D -E 0 0 B B+m3*(A-b)];
            x2 = [-A -b -a d c e];
            y2 = [-B-m3*(A-b) -B 0 0 E D];
            plot(app.UIAxes,x1,y1,x2,y2);
%             axis(app.UIAxes,[-4*b 4*b -2*B 2*B]);

            % plot input sin
            A = app.ASlider.Value;
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a/A);
            theta_2 = asin(b/A);
            theta_3 = asin(c/A);
            theta_4 = asin(d/A);
            theta_5 = asin(e/A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;
            
            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            y_output = [0 B y_rad1 B D E 0 0 -B y_rad2 -B -D -E 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            legend(app.UIAxes_IO,{'input','output'},'Location','southwest');
            
            % add key points
            keyp_x = [theta_1 theta_2 pi/2 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 3*pi/2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            keyp_y = [0 B H+M B D E 0 0 -B -H-M -B -D -E 0];
            %           formatSpec = '$\textcircled{%d}$';
            formatSpec = '\n{%d}';
            
            for i = 1:length(keyp_x)
                str_index = sprintf(formatSpec,i);
                text(app.UIAxes_IO, keyp_x(i),keyp_y(i),str_index,'Interpreter','latex')
            end
            
            % describing function
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            theta_3 = asin(c./A);
            theta_4 = asin(d./A);
            theta_5 = asin(e./A);
            g_A = (1/pi) * (- m1*(theta_1+theta_4) +...
                2*(m1-m3)*theta_2 + ...
                (m1-m2)*(theta_3-theta_5) + pi*m3 +...
                m1/2*(sin(2*theta_1)+sin(2*theta_4))-...
                (m1-m3)*sin(2*theta_2) - ...
                (m1-m2)/2*(sin(2*theta_3)-sin(2*theta_5))-...
                2*m1*a./A.*cos(theta_1) +...
                4*(m1-m3)*b./A.*cos(theta_2) +...
                2*(m1-m2)*c./A.*cos(theta_3) -...
                2*m1*d./A.*cos(theta_4)-...
                2*(m1-m2)*e./A.*cos(theta_5));
            plot(app.UIAxes_DF,A,g_A);
            b_A = (1/pi) * (m1/2*(cos(2*theta_1)-cos(2*theta_4)) + ...
                (m1-m2)/2*(cos(2*theta_3)-cos(2*theta_5)) + ...
                2*m1*a./A.*sin(theta_1) + ...
                2*(m1-m2)*c./A.*sin(theta_3) - ...
                2*m1*d./A.*sin(theta_4) - ...
                2*(m1-m2)*e./A.*sin(theta_5));
           plot(app.UIAxes_DF_Im,A,b_A);
        end

        % Value changing function: m3Slider
        function m3SliderValueChanging(app, event)
            
            m3 = event.Value;
            A = app.ASlider.Value;
            B = app.BSlider.Value;
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            e = app.eSlider.Value;
            c = app.cSlider.Value;
            d = app.dSlider.Value;
            
            % constraints on a,b,c
            m3 =max(m3,0);
            app.m3EditField.Value = m3;
            m1=B/(b-a);
            E=m1*(c-d);
            D=m1*(e-a);
            m2=(D-E)/(e-c);
            app.m1EditField.Value = m1;
            app.m2EditField.Value = m2;
            app.EEditField.Value = E;
            app.DEditField.Value = D;
            
            %Plot nonlinearity
            x1 = [-e -c -d a b A];
            y1 = [-D -E 0 0 B B+m3*(A-b)];
            x2 = [-A -b -a d c e];
            y2 = [-B-m3*(A-b) -B 0 0 E D];
            plot(app.UIAxes,x1,y1,x2,y2);
%             axis(app.UIAxes,[-4*b 4*b -2*B 2*B]);
            
            % plot input sin
            A = app.ASlider.Value;
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a/A);
            theta_2 = asin(b/A);
            theta_3 = asin(c/A);
            theta_4 = asin(d/A);
            theta_5 = asin(e/A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;
            
            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            y_output = [0 B y_rad1 B D E 0 0 -B y_rad2 -B -D -E 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            legend(app.UIAxes_IO,{'input','output'},'Location','southwest');
            
            % add key points
            keyp_x = [theta_1 theta_2 pi/2 pi-theta_2 pi-theta_5 pi-theta_3 pi-theta_4 pi+theta_1 pi+theta_2 3*pi/2 2*pi-theta_2 2*pi-theta_5 2*pi-theta_3 2*pi-theta_4];
            keyp_y = [0 B H+M B D E 0 0 -B -H-M -B -D -E 0];
            formatSpec = '\n{%d}';
            for i = 1:length(keyp_x)
                str_index = sprintf(formatSpec,i);
                text(app.UIAxes_IO, keyp_x(i),keyp_y(i),str_index,'Interpreter','latex')
            end
            
            % describing function
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            theta_3 = asin(c./A);
            theta_4 = asin(d./A);
            theta_5 = asin(e./A);
            g_A = (1/pi) * (- m1*(theta_1+theta_4) +...
                2*(m1-m3)*theta_2 + ...
                (m1-m2)*(theta_3-theta_5) + pi*m3 +...
                m1/2*(sin(2*theta_1)+sin(2*theta_4))-...
                (m1-m3)*sin(2*theta_2) - ...
                (m1-m2)/2*(sin(2*theta_3)-sin(2*theta_5))-...
                2*m1*a./A.*cos(theta_1) +...
                4*(m1-m3)*b./A.*cos(theta_2) +...
                2*(m1-m2)*c./A.*cos(theta_3) -...
                2*m1*d./A.*cos(theta_4)-...
                2*(m1-m2)*e./A.*cos(theta_5));
            plot(app.UIAxes_DF,A,g_A);
            b_A = (1/pi) * (m1/2*(cos(2*theta_1)-cos(2*theta_4)) + ...
                (m1-m2)/2*(cos(2*theta_3)-cos(2*theta_5)) + ...
                2*m1*a./A.*sin(theta_1) + ...
                2*(m1-m2)*c./A.*sin(theta_3) - ...
                2*m1*d./A.*sin(theta_4) - ...
                2*(m1-m2)*e./A.*sin(theta_5));
           plot(app.UIAxes_DF_Im,A,b_A);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {635, 635};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {276, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WithMemoryLabel = uilabel(app.LeftPanel);
            app.WithMemoryLabel.Text = 'With Memory';
            app.aSliderLabel = uilabel(app.LeftPanel);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.LeftPanel);
            app.aSlider.Limits = [0 1];
            app.aSlider.MajorTicks = [0 1];
            app.aSlider.MajorTickLabels = {'0', '1'};
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.MinorTicks = [];
            app.aSlider.Value = 0.3;
            app.BSliderLabel = uilabel(app.LeftPanel);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.LeftPanel);
            app.BSlider.Limits = [0 2];
            app.BSlider.MajorTicks = [0 2];
            app.BSlider.MajorTickLabels = {'0', '2'};
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @BSliderValueChanging, true);
            app.BSlider.MinorTicks = [];
            app.BSlider.Value = 0.5;
            app.bSliderLabel = uilabel(app.LeftPanel);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.LeftPanel);
            app.bSlider.Limits = [0 1];
            app.bSlider.MajorTicks = [0 1];
            app.bSlider.MajorTickLabels = {'0', '1'};
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.bSlider.MinorTicks = [];
            app.bSlider.Value = 0.5;
            app.cSliderLabel = uilabel(app.LeftPanel);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.LeftPanel);
            app.cSlider.Limits = [0 1];
            app.cSlider.MajorTicks = [0 1];
            app.cSlider.MajorTickLabels = {'0', '1'};
            app.cSlider.ValueChangingFcn = createCallbackFcn(app, @cSliderValueChanging, true);
            app.cSlider.MinorTicks = [];
            app.cSlider.Value = 0.2;
            app.dSliderLabel = uilabel(app.LeftPanel);
            app.dSliderLabel.Text = 'd';
            app.dSlider = uislider(app.LeftPanel);
            app.dSlider.Limits = [0 1];
            app.dSlider.MajorTicks = [0 1];
            app.dSlider.MajorTickLabels = {'0', '1'};
            app.dSlider.ValueChangedFcn = createCallbackFcn(app, @dSliderValueChanged, true);
            app.dSlider.ValueChangingFcn = createCallbackFcn(app, @dSliderValueChanging, true);
            app.dSlider.MinorTicks = [];
            app.dSlider.Value = 0.1;
            app.eSliderLabel = uilabel(app.LeftPanel);
            app.eSliderLabel.Text = 'e';
            app.eSlider = uislider(app.LeftPanel);
            app.eSlider.Limits = [0 1];
            app.eSlider.MajorTicks = [0 1];
            app.eSlider.MajorTickLabels = {'0', '1'};
            app.eSlider.ValueChangingFcn = createCallbackFcn(app, @eSliderValueChanging, true);
            app.eSlider.MinorTicks = [];
            app.eSlider.Value = 0.4;
            app.m3SliderLabel = uilabel(app.LeftPanel);
            app.m3SliderLabel.Text = 'm3';
            app.m3Slider = uislider(app.LeftPanel);
            app.m3Slider.Limits = [0 2];
            app.m3Slider.MajorTicks = [0 2];
            app.m3Slider.MajorTickLabels = {'0', '2'};
            app.m3Slider.ValueChangingFcn = createCallbackFcn(app, @m3SliderValueChanging, true);
            app.m3Slider.MinorTicks = [];
            app.m3Slider.Value = 0.5;
            app.ASliderLabel = uilabel(app.LeftPanel);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.LeftPanel);
            app.ASlider.Limits = [0 2];
            app.ASlider.MajorTicks = [0 2];
            app.ASlider.MajorTickLabels = {'0', '2'};
            app.ASlider.ValueChangingFcn = createCallbackFcn(app, @ASliderValueChanging, true);
            app.ASlider.MinorTicks = [];
            app.ASlider.Value = 1;
            app.m3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.m3EditField.ValueDisplayFormat = '%.2f';
            app.m3EditField.Value = 0.5;
            app.BEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BEditField.Limits = [0 2];
            app.BEditField.ValueDisplayFormat = '%.2f';
            app.BEditField.Value = 0.5;
            app.dEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dEditField.Limits = [0 1];
            app.dEditField.ValueDisplayFormat = '%.2f';
            app.dEditField.Value = 0.1;
            app.cEditField = uieditfield(app.LeftPanel, 'numeric');
            app.cEditField.Limits = [0 1];
            app.cEditField.ValueDisplayFormat = '%.2f';
            app.cEditField.Value = 0.2;
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.Limits = [0 1];
            app.aEditField.ValueDisplayFormat = '%.2f';
            app.aEditField.Value = 0.3;
            app.eEditField = uieditfield(app.LeftPanel, 'numeric');
            app.eEditField.Limits = [0 1];
            app.eEditField.ValueDisplayFormat = '%.2f';
            app.eEditField.Value = 0.4;
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.Limits = [0 1];
            app.bEditField.ValueDisplayFormat = '%.2f';
            app.bEditField.Value = 0.5;
            app.AEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AEditField.Limits = [0 2];
            app.AEditField.ValueDisplayFormat = '%.2f';
            app.AEditField.Value = 1;
            app.m1EditFieldLabel = uilabel(app.LeftPanel);
            app.m1EditFieldLabel.Text = 'm1';
            app.m1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.m1EditField.ValueDisplayFormat = '%.2f';
            app.m2Label = uilabel(app.LeftPanel);
            app.m2Label.Text = 'm2';
            app.m2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.m2EditField.ValueDisplayFormat = '%.2f';
            app.EEditFieldLabel = uilabel(app.LeftPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EEditField.ValueDisplayFormat = '%.2f';
            app.DEditFieldLabel = uilabel(app.LeftPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DEditField.ValueDisplayFormat = '%.2f';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Nonlinear Characteristic')
            xlabel(app.UIAxes, 'input')
            ylabel(app.UIAxes, 'output')
            app.UIAxes_DF = uiaxes(app.RightPanel);
            title(app.UIAxes_DF, 'Describing Function (real part)')
            xlabel(app.UIAxes_DF, 'Amplitude')
            ylabel(app.UIAxes_DF, 'Value')
            app.UIAxes_DF_Im = uiaxes(app.RightPanel);
            title(app.UIAxes_DF_Im, 'Describing Function (imaginary part)')
            xlabel(app.UIAxes_DF_Im, 'Amplitude')
            ylabel(app.UIAxes_DF_Im, 'Value')
            app.UIAxes_IO = uiaxes(app.RightPanel);
            title(app.UIAxes_IO, 'Input & Output')
            xlabel(app.UIAxes_IO, 'time')
            ylabel(app.UIAxes_IO, {'value'; ''})
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = with_memory

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end