classdef without_memory_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        m3EditField         matlab.ui.control.NumericEditField
        m3EditFieldLabel    matlab.ui.control.Label
        m1EditField         matlab.ui.control.NumericEditField
        m1EditFieldLabel    matlab.ui.control.Label
        WithoutmemoryLabel  matlab.ui.control.Label
        TabGroup            matlab.ui.container.TabGroup
        slopeTab            matlab.ui.container.Tab
        m3Slider            matlab.ui.control.Slider
        m3SliderLabel       matlab.ui.control.Label
        VertparamtersTab    matlab.ui.container.Tab
        BEditField          matlab.ui.control.NumericEditField
        BSlider             matlab.ui.control.Slider
        BSliderLabel        matlab.ui.control.Label
        HoriparametersTab   matlab.ui.container.Tab
        AEditField          matlab.ui.control.NumericEditField
        bEditField          matlab.ui.control.NumericEditField
        aEditField          matlab.ui.control.NumericEditField
        ASlider             matlab.ui.control.Slider
        ASliderLabel        matlab.ui.control.Label
        bSlider             matlab.ui.control.Slider
        bSliderLabel        matlab.ui.control.Label
        aSlider             matlab.ui.control.Slider
        aSliderLabel        matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        resetButton         matlab.ui.control.Button
        plotButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_Nyq          matlab.ui.control.UIAxes
        UIAxes_DF           matlab.ui.control.UIAxes
        UIAxes_IO           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        DialogApp % Dialog box app
        denominator = [1,2,1];           % denominator of the transfer function
        numerator = [0.5];               % numerator of the transfer function 
    end
    
    methods (Access = public)
        
        function updateplot(app,den,num)
            % Store inputs as properties
            den = str2num(den);
            num = str2num(num);
            
            % plot Nyquist curve
            H = tf(num,den);
            [re,im,wout] = nyquist(H);
            re = squeeze(re);
            im = squeeze(im);
%             re = [re, flip(re)];
%             im = [im, -flip(im)];
            % add unit circle
%             x = 0:pi/20:2*pi;
%             cir_x = cos(x);
%             cir_y = sin(x);
            plot(app.UIAxes_Nyq,re,im,'b-');
%             line2arrow(h);
            
            % Re-enable the Plot Options button
            app.plotButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function callButtonValueChanged(app, event)
            m=app.mEditField.Value;
            app.nEditField.Value= m;
        end

        % Callback function
        function plotButtonPushed(app, event)
            a = app.aEditField.Value;
            B = changingValue;
%             B = app.BSlider.Value;
            x = linspace(-50,50,10000);
            y = B.*(x >= a) + 0.*(x > -a & x < a) + (-B).*(x<=-a);
            plot(app.UIAxes,x,y);
            %xlim(app.UIAxes,[-5 5]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {928, 928};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {466, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)

        end

        % Value changing function: BSlider
        function BSliderValueChanging(app, event)
            B = event.Value;
            m3 = app.m3Slider.Value;
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            A = app.ASlider.Value;
            
            % Constraints
            app.BEditField.Value = B;
            app.m1EditField.Value = B/(b-a);
            
            % plot Nonlinearity
            x = [-A -b -a a b A];
            y = [-B-m3*(A-b) -B 0 0 B B+m3*(A-b)];
            plot(app.UIAxes,x,y);
            
            % plot input sin
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;

            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_1 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_1];
            y_output = [0 B y_rad1 B 0 0 -B y_rad2 -B 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
%             legend(app.UIAxes_IO,{'input','output'},'Location','southwest');


%             y_out = (B/(theta_2-theta_1)*(x-theta_1)).*(x>=theta_1 & x<=theta_2) +...
%                   (H*sin(x)+M).*(x>theta_2 & x<=(pi-theta_2)) +...
%                 (B/((pi-theta_2)-(pi-theta_1))*(x-(pi-theta_1))).*(x>(pi-theta_2) & x<=(pi-theta_1)) + ...
%                 (B/((pi-theta_2)-(pi-theta_1))*(x-(pi+theta_1))).*(x>=(pi+theta_1) & x<=(pi+theta_2)) +...
%                 (H*sin(x)-M).*(x>(pi+theta_2) & x<=(2*pi-theta_2)) + ...
%                 (B/(theta_2-theta_1)*(x-(2*pi-theta_1))).*(x>(2*pi-theta_2) & x<=(2*pi-theta_1));
          
            % add key points
            keyp1_x = [theta_1,pi-theta_1,pi+theta_1,2*pi-theta_1];
            keyp1_y = [0,0,0,0];
            keyp2_x = [theta_2,pi-theta_2,pi+theta_2,2*pi-theta_2];
            keyp2_y = [B,B,-B,-B];
            keyp3_x = [pi/2,3*pi/2];
            keyp3_y = [m3*(A-b)+B,-(m3*(A-b)+B)];
            keyps_x = [keyp1_x,keyp2_x,keyp3_x];
            keyps_y = [keyp1_y,keyp2_y,keyp3_y];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-',keyps_x,keyps_y,'bo');
            legend(app.UIAxes_IO,{'input','output','key point'},'Location','southwest');
            
            % df 
            m1 = B/(b-a);
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            if a ~= b
                g_A = (1/pi) * (m1 * (-2*theta_1 + sin(2*theta_1) - 4*a./A.*cos(theta_1)) + (m1-m3)*(2*theta_2 - sin(2*theta_2) + 4*b./A.*cos(theta_2)) + pi*m3);          
            elseif a == b
                g_A = 1/pi*(m3*(pi-2*theta_1 + sin(2*theta_1))-4./A*(m3*a-B).*cos(theta_1));
            end
            plot(app.UIAxes_DF,A,g_A);
        end

        % Value changing function: m3Slider
        function m3SliderValueChanging(app, event)
            m3 = event.Value;
            app.m3EditField.Value = m3;
            B = app.BSlider.Value;
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            A = app.ASlider.Value;
            
             % Constraints
            app.m3EditField.Value = m3;
            
            % plot Nonlinearity
            x = [-A -b -a a b A];
            y = [-B-m3*(A-b) -B 0 0 B B+m3*(A-b)];
            plot(app.UIAxes,x,y);
            
            % plot input sin
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
            % plot output after Nonlinearity
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;

            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_1 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_1];
            y_output = [0 B y_rad1 B 0 0 -B y_rad2 -B 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            
            % add key points
            keyp1_x = [theta_1,pi-theta_1,pi+theta_1,2*pi-theta_1];
            keyp1_y = [0,0,0,0];
            keyp2_x = [theta_2,pi-theta_2,pi+theta_2,2*pi-theta_2];
            keyp2_y = [B,B,-B,-B];
            keyp3_x = [pi/2,3*pi/2];
            keyp3_y = [m3*(A-b)+B,-(m3*(A-b)+B)];
            keyps_x = [keyp1_x,keyp2_x,keyp3_x];
            keyps_y = [keyp1_y,keyp2_y,keyp3_y];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-',keyps_x,keyps_y,'bo');
            legend(app.UIAxes_IO,{'input','output','key point'},'Location','southwest');
            
            % df 
            m1 = B/(b-a);
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            if a ~= b
                g_A = (1/pi) * (m1 * (-2*theta_1 + sin(2*theta_1) - 4*a./A.*cos(theta_1)) + (m1-m3)*(2*theta_2 - sin(2*theta_2) + 4*b./A.*cos(theta_2)) + pi*m3);          
            elseif a == b
                g_A = 1/pi*(m3*(pi-2*theta_1 + sin(2*theta_1))-4./A*(m3*a-B).*cos(theta_1));
            end
            plot(app.UIAxes_DF,A,g_A);
        end

        % Callback function
        function dSliderValueChanging(app, event)
            m3 = app.m3Slider.Value;
            B = app.BSlider.Value;
            a = event.Value;
            
             % Constraints
            app.dEditField.Value = d;
            
            x = linspace(-50,50,10000);
            y = (m3*(x-a)+B).*(x >= a) + 0.*(x > -a & x < a) + (m3*(x+a)-B).*(x<=-a);
            plot(app.UIAxes,x,y);
        end

        % Callback function
        function m1SliderValueChanging(app, event)
            m1 = event.Value;
            m3 = app.m3Slider.Value;
            B = app.BSlider.Value;
            a = event.Value;
            x = linspace(-50,50,10000);
            y = (m3*(x-a)+B).*(x >= a) + 0.*(x > -a & x < a) + (m3*(x+a)-B).*(x<=-a);
            plot(app.UIAxes,x,y);
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            % plot Nonlinearity curve
            a = event.Value;
            B = app.BSlider.Value;
            b = app.bSlider.Value;
            m3 = app.m3Slider.Value;
            A = app.ASlider.Value;
            
            % Constraints
            a = min(b,a);
            app.aEditField.Value = a;
            app.m1EditField.Value = B/(b-a);
            
            % plot Nonlinearity
            x = [-A -b -a a b A];
            y = [-B-m3*(A-b) -B 0 0 B B+m3*(A-b)];
            plot(app.UIAxes,x,y);
            
            % plot input sin
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
             % plot output after Nonlinearity
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;

            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_1 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_1];
            y_output = [0 B y_rad1 B 0 0 -B y_rad2 -B 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            
            % add key points
            keyp1_x = [theta_1,pi-theta_1,pi+theta_1,2*pi-theta_1];
            keyp1_y = [0,0,0,0];
            keyp2_x = [theta_2,pi-theta_2,pi+theta_2,2*pi-theta_2];
            keyp2_y = [B,B,-B,-B];
            keyp3_x = [pi/2,3*pi/2];
            keyp3_y = [m3*(A-b)+B,-(m3*(A-b)+B)];
            keyps_x = [keyp1_x,keyp2_x,keyp3_x];
            keyps_y = [keyp1_y,keyp2_y,keyp3_y];
           plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-',keyps_x,keyps_y,'bo');
            legend(app.UIAxes_IO,{'input','output','key point'},'Location','southwest');
            
            % plot Describing func
            m1 = B/(b-a);
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            if a ~= b
                g_A = (1/pi) * (m1 * (-2*theta_1 + sin(2*theta_1) - 4*a./A.*cos(theta_1)) + (m1-m3)*(2*theta_2 - sin(2*theta_2) + 4*b./A.*cos(theta_2)) + pi*m3);          
            elseif a == b
                g_A = 1/pi*(m3*(pi-2*theta_1 + sin(2*theta_1))-4./A*(m3*a-B).*cos(theta_1));
            end
            plot(app.UIAxes_DF,A,g_A);
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            % plot Nonlinearity curve
            b = event.Value;
            B = app.BSlider.Value;
            a = app.aSlider.Value;
            m3 = app.m3Slider.Value;
            A = app.ASlider.Value;
            
            %Constraints
            b = min(max(a,b),A);
            app.bEditField.Value = b;
            app.m1EditField.Value = B/(b-a);
            
            % plot Nonlinearity
            x = [-A -b -a a b A];
            y = [-B-m3*(A-b) -B 0 0 B B+m3*(A-b)];
            plot(app.UIAxes,x,y);
            
            % plot input sin
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
             % plot output after Nonlinearity
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;

            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_1 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_1];
            y_output = [0 B y_rad1 B 0 0 -B y_rad2 -B 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            
            % add key points
            keyp1_x = [theta_1,pi-theta_1,pi+theta_1,2*pi-theta_1];
            keyp1_y = [0,0,0,0];
            keyp2_x = [theta_2,pi-theta_2,pi+theta_2,2*pi-theta_2];
            keyp2_y = [B,B,-B,-B];
            keyp3_x = [pi/2,3*pi/2];
            keyp3_y = [m3*(A-b)+B,-(m3*(A-b)+B)];
            keyps_x = [keyp1_x,keyp2_x,keyp3_x];
            keyps_y = [keyp1_y,keyp2_y,keyp3_y];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-',keyps_x,keyps_y,'bo');
            legend(app.UIAxes_IO,{'input','output','key point'},'Location','southwest');
            
            % plot Describing func
            m1 = B/(b-a);
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            if a ~= b
                g_A = (1/pi) * (m1 * (-2*theta_1 + sin(2*theta_1) - 4*a./A.*cos(theta_1)) + (m1-m3)*(2*theta_2 - sin(2*theta_2) + 4*b./A.*cos(theta_2)) + pi*m3);          
            elseif a == b
                g_A = 1/pi*(m3*(pi-2*theta_1 + sin(2*theta_1))-4./A*(m3*a-B).*cos(theta_1));
            end
            plot(app.UIAxes_DF,A,g_A);
        end

        % Value changed function: m1EditField
        function m1EditFieldValueChanged(app, event)
value = app.m1EditField.Value;

        end

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.plotButton.Value;
            plot()
        end

        % Value changing function: ASlider
        function ASliderValueChanging(app, event)
            % get value 
            b = app.bSlider.Value;
            B = app.BSlider.Value;
            a = app.aSlider.Value;
            m3 = app.m3Slider.Value;
            A = event.Value;
            
            % Constraint
            A =max(b,A);
            app.AEditField.Value = A;
            
            % plot Nonlinearity
            x = [-A -b -a a b A];
            y = [-B-m3*(A-b) -B 0 0 B B+m3*(A-b)];
            plot(app.UIAxes,x,y);
            
            % plot input sin
            x = linspace(0,2*pi,10000);
            y = A*sin(x);
            
             % plot output after Nonlinearity
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            H = m3*(A-b)/(1-sin(theta_2));
            M = m3*(A-b)+B-H;

            x_rad1 = linspace(theta_2,pi-theta_2,100);
            y_rad1 = (H*sin(x_rad1)+M);
            x_rad2 = linspace(pi+theta_2,2*pi-theta_2,100);
            y_rad2 = (H*sin(x_rad2)-M);
            
            x_output = [theta_1 theta_2 x_rad1 pi-theta_2 pi-theta_1 pi+theta_1 pi+theta_2 x_rad2 2*pi-theta_2 2*pi-theta_1];
            y_output = [0 B y_rad1 B 0 0 -B y_rad2 -B 0];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-');
            
            % add key points
            keyp1_x = [theta_1,pi-theta_1,pi+theta_1,2*pi-theta_1];
            keyp1_y = [0,0,0,0];
            keyp2_x = [theta_2,pi-theta_2,pi+theta_2,2*pi-theta_2];
            keyp2_y = [B,B,-B,-B];
            keyp3_x = [pi/2,3*pi/2];
            keyp3_y = [m3*(A-b)+B,-(m3*(A-b)+B)];
            keyps_x = [keyp1_x,keyp2_x,keyp3_x];
            keyps_y = [keyp1_y,keyp2_y,keyp3_y];
            plot(app.UIAxes_IO,x,y,'k--',x_output,y_output,'r-',keyps_x,keyps_y,'bo');
            legend(app.UIAxes_IO,{'input','output','key point'},'Location','southwest');
            
            % df
            m1 = B/(b-a);
            A = linspace(0,10,500);
            theta_1 = asin(a./A);
            theta_2 = asin(b./A);
            if a ~= b
                g_A = (1/pi) * (m1 * (-2*theta_1 + sin(2*theta_1) - 4*a./A.*cos(theta_1)) + (m1-m3)*(2*theta_2 - sin(2*theta_2) + 4*b./A.*cos(theta_2)) + pi*m3);          
            elseif a == b
                g_A = 1/pi*(m3*(pi-2*theta_1 + sin(2*theta_1))-4./A*(m3*a-B).*cos(theta_1));
            end
            plot(app.UIAxes_DF,A,g_A);
        end

        % Button pushed function: plotButton
        function plotButtonPushed2(app, event)
            % Disable Plot Options button while dialog is open
            app.plotButton.Enable = 'off';
            
            % Open the Nyquist dialog and pass inputs
            app.DialogApp = Nyquist_Dialog(app, app.denominator, app.numerator);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.plotButton.Enable = 'on';
            
            cla(app.UIAxes_Nyq)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.slopeTab = uitab(app.TabGroup);
            app.slopeTab.Title = 'slope';
            app.m3SliderLabel = uilabel(app.slopeTab);
            app.m3SliderLabel.Text = 'm3';
            app.m3Slider = uislider(app.slopeTab);
            app.m3Slider.Limits = [0 2];
            app.m3Slider.MajorTicks = [0 2];
            app.m3Slider.MajorTickLabels = {'0', '2', ''};
            app.m3Slider.ValueChangingFcn = createCallbackFcn(app, @m3SliderValueChanging, true);
            app.m3Slider.MinorTicks = [];
            app.m3Slider.Value = 0.5;
            app.VertparamtersTab = uitab(app.TabGroup);
            app.VertparamtersTab.Title = 'Vert-paramters';
            app.BSliderLabel = uilabel(app.VertparamtersTab);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.VertparamtersTab);
            app.BSlider.Limits = [0 2];
            app.BSlider.MajorTicks = [0 2];
            app.BSlider.MajorTickLabels = {'0', '2'};
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @BSliderValueChanging, true);
            app.BSlider.MinorTicks = [];
            app.BSlider.Value = 0.5;
            app.BEditField = uieditfield(app.VertparamtersTab, 'numeric');
            app.BEditField.Limits = [0 2];
            app.BEditField.ValueDisplayFormat = '%.2f';
            app.BEditField.Value = 0.5;
            app.HoriparametersTab = uitab(app.TabGroup);
            app.HoriparametersTab.Title = 'Hori-parameters';
            app.aSliderLabel = uilabel(app.HoriparametersTab);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.HoriparametersTab);
            app.aSlider.Limits = [0 1];
            app.aSlider.MajorTicks = [0 1];
            app.aSlider.MajorTickLabels = {'0', '1', ''};
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.MinorTicks = [];
            app.aSlider.Value = 0.3;
            app.bSliderLabel = uilabel(app.HoriparametersTab);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.HoriparametersTab);
            app.bSlider.Limits = [0 1];
            app.bSlider.MajorTicks = [0 1];
            app.bSlider.MajorTickLabels = {'0', '1', ''};
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.bSlider.MinorTicks = [];
            app.bSlider.Value = 0.5;
            app.ASliderLabel = uilabel(app.HoriparametersTab);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.HoriparametersTab);
            app.ASlider.Limits = [0 2];
            app.ASlider.MajorTicks = [0 2];
            app.ASlider.MajorTickLabels = {'0', '2'};
            app.ASlider.ValueChangingFcn = createCallbackFcn(app, @ASliderValueChanging, true);
            app.ASlider.MinorTicks = [];
            app.ASlider.Value = 1;
            app.aEditField = uieditfield(app.HoriparametersTab, 'numeric');
            app.aEditField.Limits = [0 1];
            app.aEditField.ValueDisplayFormat = '%.2f';
            app.aEditField.Value = 0.3;
            app.bEditField = uieditfield(app.HoriparametersTab, 'numeric');
            app.bEditField.Limits = [0 1];
            app.bEditField.ValueDisplayFormat = '%.2f';
            app.bEditField.Value = 0.5;
            app.AEditField = uieditfield(app.HoriparametersTab, 'numeric');
            app.AEditField.Limits = [0 2];
            app.AEditField.ValueDisplayFormat = '%.2f';
            app.AEditField.Value = 1;
            app.WithoutmemoryLabel = uilabel(app.LeftPanel);
            app.WithoutmemoryLabel.Text = 'Without memory';
            app.m1EditFieldLabel = uilabel(app.LeftPanel);
            app.m1EditFieldLabel.Text = 'm1';
            app.m1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.m1EditField.ValueDisplayFormat = '%.2f';
            app.m1EditField.ValueChangedFcn = createCallbackFcn(app, @m1EditFieldValueChanged, true);
            app.m3EditFieldLabel = uilabel(app.LeftPanel);
            app.m3EditFieldLabel.Text = 'm3';
            app.m3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.m3EditField.ValueDisplayFormat = '%.2f';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_IO = uiaxes(app.RightPanel);
            title(app.UIAxes_IO, 'Input & Output')
            xlabel(app.UIAxes_IO, 'time')
            ylabel(app.UIAxes_IO, {'value'; ''})
            app.UIAxes_DF = uiaxes(app.RightPanel);
            title(app.UIAxes_DF, 'Describing Function (real part)')
            xlabel(app.UIAxes_DF, 'Amplitude')
            ylabel(app.UIAxes_DF, 'Value')
            app.UIAxes_Nyq = uiaxes(app.RightPanel);
            title(app.UIAxes_Nyq, 'Nyquist')
            xlabel(app.UIAxes_Nyq, 'Real part')
            ylabel(app.UIAxes_Nyq, 'Imaginary part')
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Nonlinear Characteristics')
            xlabel(app.UIAxes, 'input')
            ylabel(app.UIAxes, 'output')
            app.plotButton = uibutton(app.RightPanel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed2, true);
            app.plotButton.Text = 'plot';
            app.resetButton = uibutton(app.RightPanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = without_memory_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end